classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        cluster2sizeEditField        matlab.ui.control.NumericEditField
        cluster2sizeEditFieldLabel   matlab.ui.control.Label
        NetworkSizeEditField         matlab.ui.control.NumericEditField
        NetworkSizeEditFieldLabel    matlab.ui.control.Label
        PowerEditField               matlab.ui.control.NumericEditField
        PowerEditFieldLabel          matlab.ui.control.Label
        m_permutationEditField       matlab.ui.control.NumericEditField
        m_permutationEditFieldLabel  matlab.ui.control.Label
        Button                       matlab.ui.control.Button
        mEditField                   matlab.ui.control.NumericEditField
        mEditFieldLabel              matlab.ui.control.Label
        FWERthresholdEditField       matlab.ui.control.NumericEditField
        FWERthresholdEditFieldLabel  matlab.ui.control.Label
        cluster1sizeEditField        matlab.ui.control.NumericEditField
        cluster1sizeEditFieldLabel   matlab.ui.control.Label
        rho1EditField                matlab.ui.control.NumericEditField
        rho1EditFieldLabel           matlab.ui.control.Label
        rho0EditField                matlab.ui.control.NumericEditField
        rho0EditFieldLabel           matlab.ui.control.Label
        thetaEditField               matlab.ui.control.NumericEditField
        thetaEditFieldLabel          matlab.ui.control.Label
        sigma1EditField              matlab.ui.control.NumericEditField
        sigma1EditFieldLabel         matlab.ui.control.Label
        sigma0EditField              matlab.ui.control.NumericEditField
        sigma0EditFieldLabel         matlab.ui.control.Label
        n2EditField                  matlab.ui.control.NumericEditField
        n2EditFieldLabel             matlab.ui.control.Label
        n1EditField                  matlab.ui.control.NumericEditField
        n1EditFieldLabel             matlab.ui.control.Label
    end


    methods (Access = private)

        function [W1,Wp,Clist_GT, case_mtx, ctrl_mtx, threshold_GT] = sampling_ind(app)
            % a simple data generator
            % Chuan

            % output: W1 ground truth
            %         Wp permuted data

            % Load parameters
            N = app.NetworkSizeEditField.Value;
            case_num = app.n2EditField.Value;
            ctrl_num = app.n1EditField.Value;
            sigma0 = app.sigma0EditField.Value;
            sigma1 = app.sigma1EditField.Value;
            theta = app.thetaEditField.Value;
            cluster1_size = app.cluster1sizeEditField.Value;
            cluster2_size = app.cluster2sizeEditField.Value;
            d_out_ratio = app.rho1EditField.Value;
            d_in_ratio = app.rho0EditField.Value;
            cluster_size = [cluster1_size, cluster1_size];
            Z = pick_idx(app);

            edge_num = N*(N-1)/2;
            all_idx = 1:edge_num;

            % covariate-related edges
            idx_temp = [];
            for i = 1:length(cluster_size)
                idx_temp = [idx_temp, Z{i}];
            end
            case_idx = idx_temp;
            % non-covariate-related edges
            ctrl_idx = all_idx;
            ctrl_idx(case_idx) = [];
            % add non central dist. to the PT
            d_out = floor(d_out_ratio * length(ctrl_idx));
            d_in = floor((1-d_in_ratio) * length(case_idx));
            d_out_idx = randsample(1:length(ctrl_idx),d_out,false);
            d_out_orig = ctrl_idx(d_out_idx);
            ctrl_idx(d_out_idx) = [];
            d_in_idx = randsample(1:length(case_idx),d_in,false);
            d_in_orig = case_idx(d_in_idx);
            case_idx(d_in_idx) = [];

            case_mtx = zeros(case_num, edge_num);
            case_mtx(:,case_idx) = randn(case_num, length(case_idx))*sigma1 + theta;
            case_mtx(:,d_out_orig) = randn(case_num, d_out)*sigma1 + theta;
            case_mtx(:,ctrl_idx) = randn(case_num, length(ctrl_idx))*sigma0;
            case_mtx(:,d_in_orig) = randn(case_num, d_in)*sigma0;
            ctrl_mtx = randn(ctrl_num, edge_num)*sigma0;

            pm = randperm(N);
            [~,p_vec]=ttest2(case_mtx,ctrl_mtx);
            W1=squareform(-log(p_vec));
            Wp = W1(pm,pm);
            % return ground truth Clist
            [~,Clist_GT] = sort(pm);


            %% find a good cut
            threshold_vec = logspace(-5,0,100);
            f1score_vec = zeros(length(threshold_vec),1);
            for i = 1:length(threshold_vec)
                target = ones(1,edge_num);
                target(ctrl_idx)=0;
                output = p_vec <= threshold_vec(i);
                cm = confusionmat(double(target),double(output));
                f1score_vec(i) = cm(2,2)/(cm(2,2) + 0.5*(cm(1,2) + cm(2,1)));
            end

            [~,thresh_idx] = max(f1score_vec);
            threshold_GT=threshold_vec(thresh_idx);
        end


        function Z = pick_idx(app)
            % given a vector of cluster sizes, return the corresponding indices the
            % vectorized edge list.

            cluster1_size = app.cluster1sizeEditField.Value;
            cluster2_size = app.cluster2sizeEditField.Value;
            cluster_size = [cluster1_size, cluster2_size];
            N = app.NetworkSizeEditField.Value;
            Z = cell(length(cluster_size),1);

            for i = 1:length(cluster_size)
                temp = zeros(N);
                if i == 1
                    temp(1:cluster_size(i),1:cluster_size(i)) = 1;
                else
                    temp(sum(cluster_size(1:i-1))+1:sum(cluster_size(1:i)), sum(cluster_size(1:i-1))+1:sum(cluster_size(1:i))) = 1;
                end
                for j = 1:N
                    temp(j,j) = 0;
                end
                Z{i} = find(squareform(temp));
            end
        end

        function [W_DSD_greedy, Clist, CID] = greedy(app, Wp, func, threshold_DSD)
            % a greedy version of the greedy peeling algorithm
            % the algorithm finds a cluster at each time until the average value in the
            % remainder adjacency matrix is below the mean of the input cluster
            % Chuan

            % initialize
            Wp_DSD = Wp;
            Wp_DSD(Wp_DSD<-log(threshold_DSD)) = 0;

            % find the mean value of the -log(p) across all
            p_mean = mean(mean(Wp_DSD));
            % initialize mean value of the remaining portion
            p_rem = inf;
            % store all node lists from each cluster
            Node_list = {}; nc = 1;
            Clist = []; CID = [];
            orig_Node = (1:length(Wp))';
            % while loop
            while length(Clist) < length(Wp)-1
                % do greedy peeling to get the next cluster
                [~, Clist_temp,Node_Seq,  remaining_node] = OQC_greedyA(app, Wp_DSD, func);
                % update adjacency matrix
                Wp_DSD = Wp_DSD(remaining_node, remaining_node);
                % update
                p_rem = mean(mean(Wp_DSD));
                % record nodes
                Node_list{nc} = orig_Node(Clist_temp(1:length(Node_Seq)));
                Clist = [Clist; orig_Node(Clist_temp(1:length(Node_Seq)))];
                CID = [CID; length(Node_Seq)];
                % updates
                orig_Node = orig_Node(remaining_node);
                nc = nc + 1;
            end
            % uninformative nodes
            Clist = [Clist; orig_Node];
            CID = [CID; length(remaining_node)];
            W_DSD_greedy = Wp(Clist, Clist);
        end


        function [W_DSD_greedy, Clist,Node_Seq,  removing_node] = OQC_greedyA(app, Wp_DSD,func)
            % a simple implementation of the greedy algorithm from the Denser than
            % dense paper
            % note that this only extract ONE dense subgraphs
            % Chuan

            % Wp_DSD(Wp_DSD<threshold_DSD) = 0;
            N = size(Wp_DSD,1);
            Recording_Matrix = [];
            Recording_Clist = 1:N;
            Wp_temp = Wp_DSD;
            for i = N:-1:1
                idxlist_temp = 1:length(Wp_temp);
                % work on the temp matrix
                [~,idx_min_temp] = min(sum(Wp_temp));
                % find corresponding index for idx_min_temp in Recording_Clist
                idxlist_temp(idx_min_temp) = [];

                score_temp = func(Wp_temp(idxlist_temp,idxlist_temp));
                % record index and score
                Recording_Matrix = [Recording_Matrix; [Recording_Clist(idx_min_temp),score_temp] ];
                Recording_Clist(idx_min_temp) = [];
                % update matrix
                Wp_temp = Wp_temp(idxlist_temp,idxlist_temp);
            end
            [~,max_idx] = max(Recording_Matrix(:,2));
            removing_node = Recording_Matrix(1:max_idx,1);
            Node_Seq = Recording_Matrix(end:-1:max_idx+1,1);
            Clist = [Node_Seq;removing_node];
            W_DSD_greedy = Wp_DSD([Node_Seq;removing_node],[Node_Seq;removing_node]);

        end


        function P_value = permutation_testA(app, Wt, Wt2, Wp, CID0, Clist0, threshold_GT, M, func)


            % edge_density = @(C) sum(sum(C))/(size(C,1)*(size(C,1)-1));
            % node_density = @(C) sum(sum(C))/size(C,1);

            % switch between functions
            % sig_edge= res_summary.sig_edge;
            casenum = app.n2EditField.Value;
            ctrlnum = app.n1EditField.Value;
            % Wp=res_summary.Wp;
            Wt_orig = [Wt;Wt2];
            r_vec = [0.1 0.05 0.01 0.005 0.001];
            T_orig =  custom_statistic(app, Wp, Clist0, CID0, r_vec);


            T_vec = zeros(M,1);
            for m=1:M

                Wt_c=Wt_orig(randperm(casenum+ctrlnum),:);
                Wt1_c=Wt_c(1:ctrlnum,:);
                Wt2_c=Wt_c(ctrlnum+1:end,:);
                [~,ptemp]=ttest2(Wt1_c,Wt2_c);

                Wp_temp=squareform(-log(ptemp));
                [~, Clist_temp, CID_temp] = greedy(app,Wp_temp, func, threshold_GT);
                [~,T_vec(m)] = custom_statistic(app, Wp_temp, Clist_temp, CID_temp, r_vec);

            end

%             P_value = zeros(length(T_orig),1);
%             for i = 1:length(T_orig)
%                 if CID0(i) <= 2
%                     P_value(i) = 1;
%                 else
%                     P_value(i) = sum((T_vec - T_orig(i)) >0 )/M;
%                 end
%             end
            Tmax = max(T_orig);
            P_value = sum((T_vec - Tmax) >0 )/M;

        end

        function [T0_vec, score_max] = custom_statistic(app, Wp, Clist, CID, r_vec)
            % calculate test statistics from permutation test

            % get number of clusters
            cluster_len = length(CID);
            % define test statistic for each cluster
            T0_vec = zeros(cluster_len,1);
            % loop each cluster and calculate the statistic for each cluster
            for i = 1:cluster_len

                if i == 1
                    W_cluster = Wp(Clist(1:CID(1)),Clist(1:CID(1)));
                else
                    W_cluster = Wp(Clist(sum(CID(1:i-1))+1:sum(CID(1:i-1))+CID(i)), ...
                        Clist(sum(CID(1:i-1))+1:sum(CID(1:i-1))+CID(i)));
                end

                if size(W_cluster,1) <= 5
                    T0_vec(i) = 0;

                else
                    % initialize score for the current cluster
                    score = zeros(length(r_vec),1);
                    % calculate the statistic
                    for j = 1:length(r_vec)
                        if W_cluster == 0
                            score(j) = 0;
                        else
                            cur_pvec = squareform(W_cluster);
                            cur_pvec(cur_pvec < -log(r_vec(j))) = 0;
                            cur_pvec(cur_pvec >= -log(r_vec(j))) = 1;
                            score(j) = sum(cur_pvec)/(length(W_cluster));
                        end

                    end
                    T0_vec(i) = [0.2 0.2 0.2 0.2 0.2]*score;

                end

            end
            % get the maximum of the scores across all clusters
            [score_max,id_max] = max(T0_vec);
        end

        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: Button
        function ButtonPushed(app, event)
            % Number of HC
            n1 = app.n1EditField.Value;
            % Number of Patients
            n2 = app.n2EditField.Value;
            % STD of HC normal distribution
            sigma0 = app.sigma0EditField.Value;
            % STD of Patients normal distribution
            sigma1 = app.sigma1EditField.Value;
            % difference between the mean values mu1 - mu0
            theta = app.thetaEditField.Value;
            % proportion of significant edges of the covariate-related subnetwork
            rho0 = app.rho0EditField.Value;
            % proportion of insignificant edges of the outside the covariate-related subnetwork
            rho1 = app.rho1EditField.Value;
            % number of nodes in the covariate-related subnetworks
            cluster_size = app.cluster1sizeEditField.Value;

            FWER_threshold = app.FWERthresholdEditField.Value;
            % Number of repetitions
            M_rep = app.mEditField.Value;
            % Number of permutation tests in one repetition
            m_permutation = app.m_permutationEditField.Value;
            % Size of the entire network
            N = app.NetworkSizeEditField.Value;

            % power list
            power_list = zeros(M_rep,1);
            for m = 1:M_rep
                func = @(C) sum(sum(C))/length(C)^1.5;
                [W1,Wp, Clist_GT, case_mtx, ctrl_mtx, threshold_GT] = sampling_ind(app);
                % greedy
                [W_greedy, Clist_greedy, CID_greedy] = greedy(app, Wp, func, 1);

                disp(CID_greedy);
                P_value = permutation_testA(app, ctrl_mtx, case_mtx, Wp, CID_greedy, Clist_greedy, 1, m_permutation, func);
                power_list(m) = double(P_value<= FWER_threshold);
            end


            power = mean(power_list);
            app.PowerEditField.Value = power;

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.n1EditFieldLabel = uilabel(app.UIFigure);
            app.n1EditFieldLabel.Text = 'n1';
            app.n1EditField = uieditfield(app.UIFigure, 'numeric');
            app.n1EditField.Value = 30;
            app.n2EditFieldLabel = uilabel(app.UIFigure);
            app.n2EditFieldLabel.Text = 'n2';
            app.n2EditField = uieditfield(app.UIFigure, 'numeric');
            app.n2EditField.Value = 30;
            app.sigma0EditFieldLabel = uilabel(app.UIFigure);
            app.sigma0EditFieldLabel.Text = 'sigma0';
            app.sigma0EditField = uieditfield(app.UIFigure, 'numeric');
            app.sigma0EditField.Value = 1;
            app.sigma1EditFieldLabel = uilabel(app.UIFigure);
            app.sigma1EditFieldLabel.Text = 'sigma1';
            app.sigma1EditField = uieditfield(app.UIFigure, 'numeric');
            app.sigma1EditField.Value = 1;
            app.thetaEditFieldLabel = uilabel(app.UIFigure);
            app.thetaEditFieldLabel.Text = 'theta';
            app.thetaEditField = uieditfield(app.UIFigure, 'numeric');
            app.thetaEditField.Value = 1;
            app.rho0EditFieldLabel = uilabel(app.UIFigure);
            app.rho0EditFieldLabel.Text = 'rho0';
            app.rho0EditField = uieditfield(app.UIFigure, 'numeric');
            app.rho0EditField.Value = 0.35;
            app.rho1EditFieldLabel = uilabel(app.UIFigure);
            app.rho1EditFieldLabel.Text = 'rho1';
            app.rho1EditField = uieditfield(app.UIFigure, 'numeric');
            app.rho1EditField.Value = 0.05;
            app.cluster1sizeEditFieldLabel = uilabel(app.UIFigure);
            app.cluster1sizeEditFieldLabel.Text = 'cluster 1 size';
            app.cluster1sizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.cluster1sizeEditField.Value = 25;
            app.FWERthresholdEditFieldLabel = uilabel(app.UIFigure);
            app.FWERthresholdEditFieldLabel.Text = 'FWER threshold';
            app.FWERthresholdEditField = uieditfield(app.UIFigure, 'numeric');
            app.mEditFieldLabel = uilabel(app.UIFigure);
            app.mEditFieldLabel.Text = 'm';
            app.mEditField = uieditfield(app.UIFigure, 'numeric');
            app.mEditField.Value = 20;
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.m_permutationEditFieldLabel = uilabel(app.UIFigure);
            app.m_permutationEditFieldLabel.Text = 'm_permutation';
            app.m_permutationEditField = uieditfield(app.UIFigure, 'numeric');
            app.m_permutationEditField.Value = 50;
            app.PowerEditFieldLabel = uilabel(app.UIFigure);
            app.PowerEditFieldLabel.Text = 'Power';
            app.PowerEditField = uieditfield(app.UIFigure, 'numeric');
            app.NetworkSizeEditFieldLabel = uilabel(app.UIFigure);
            app.NetworkSizeEditFieldLabel.Text = 'Network Size';
            app.NetworkSizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.NetworkSizeEditField.Value = 140;
            app.cluster2sizeEditFieldLabel = uilabel(app.UIFigure);
            app.cluster2sizeEditFieldLabel.Text = 'cluster 2 size';
            app.cluster2sizeEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end